classdef app_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SetupButton              matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        XEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        ZEditField               matlab.ui.control.NumericEditField
        MATCHButton              matlab.ui.control.Button
        Joint1SliderLabel        matlab.ui.control.Label
        Joint1Slider             matlab.ui.control.Slider
        Joint2SliderLabel        matlab.ui.control.Label
        Joint2Slider             matlab.ui.control.Slider
        Joint3SliderLabel        matlab.ui.control.Label
        Joint3Slider             matlab.ui.control.Slider
        ESTOPLampLabel           matlab.ui.control.Label
        ESTOPLamp                matlab.ui.control.Lamp
        SwitchLabel              matlab.ui.control.Label
        Switch                   matlab.ui.control.Switch
        ClearButton              matlab.ui.control.Button
        RollEditFieldLabel       matlab.ui.control.Label
        RollEditField            matlab.ui.control.NumericEditField
        PitchEditFieldLabel      matlab.ui.control.Label
        PitchEditField           matlab.ui.control.NumericEditField
        YawEditFieldLabel        matlab.ui.control.Label
        YawEditField             matlab.ui.control.NumericEditField
        Joint4SliderLabel        matlab.ui.control.Label
        Joint4Slider             matlab.ui.control.Slider
        Joint5SliderLabel        matlab.ui.control.Label
        Joint5Slider             matlab.ui.control.Slider
        Joint6SliderLabel        matlab.ui.control.Label
        Joint6Slider             matlab.ui.control.Slider
        PlanarPlaneLabel         matlab.ui.control.Label
        CartesianPlaneqlimLabel  matlab.ui.control.Label
        ESTOPButton              matlab.ui.control.Button
        RESUMEButton             matlab.ui.control.Button
    end

% ! PLEASE NOTE !
% GUI examples and code were found on the following website:
% https://au.mathworks.com/matlabcentral/fileexchange/24861-41-complete-gui-examples
% examples 11 (stop for loop), 13 (slider change) and few others are referenced in this GUI 
    
    properties (Access = private)
        robot = Kinova;        
    end

    methods (Access = private)
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all
            clear
            clc
            set(0, 'DefaultFigureWindowStyle','docked')
        end

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            w = 3;
            workspace =  [-w w -w w 0 w];
            
            %generate & Plot table, arm & card holders
            
            Dennis = People(workspace, 'Dennis', transl(1.1, 0.4, 0.0) * trotz(pi));
            Mei = People(workspace, 'Mei', transl(-1.15, 0.55, 0.0) * trotz(-pi/2));
            Jack = People(workspace, 'Jack', transl(0.0, 1.15, 0.0));
            
            table = getTable;
            robot = Kinova;
            CH = getCardHolders;
            cards = getCards;
            bottle = getBottle;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            moveCards();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            close all
            clear
            clc
            set(0, 'DefaultFigureWindowStyle','docked')
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            while app.ESTOPButton.ButtonPushedFcn == true
                disp('E-Stop Pushed: Stopping Game');
                %uiwait();
                pause(1);
            end
        end

        % Button pushed function: RESUMEButton
        function RESUMEButtonPushed(app, event)
            if RESUMEButtonPushed(app, @RESUMEButtonPushed) == true
                disp('Resuming Game');
                uiresume();
            end
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            value = app.Joint1Slider.Value;
            Joint1 = app.Joint1Slider.Value;
            Joint2 = app.Joint2Slider.Value;
            Joint3 = app.Joint3Slider.Value;
            Joint4 = app.Joint4Slider.Value;
            Joint5 = app.Joint5Slider.Value;
            Joint6 = app.Joint6Slider.Value;
            
            if app.Joint1Slider.Value
                app.robot.model.animate([Joint1, Joint2, Joint3, Joint4, Joint5, Joint6])
            end
            
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            value = app.Joint2Slider.Value;
            
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            value = app.Joint3Slider.Value;
            
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            value = app.Joint4Slider.Value;
            
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            value = app.Joint5Slider.Value;
            
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            value = app.Joint6Slider.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.MATCHButton = uibutton(app.UIFigure, 'push');
            app.MATCHButton.Text = 'MATCH';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-154 154];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-150 150];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-150 150];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.ESTOPLampLabel = uilabel(app.UIFigure);
            app.ESTOPLampLabel.Text = 'E-STOP';
            app.ESTOPLamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-149 149];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-145 145];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-149 149];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.PlanarPlaneLabel = uilabel(app.UIFigure);
            app.PlanarPlaneLabel.Text = 'Planar Plane';
            app.CartesianPlaneqlimLabel = uilabel(app.UIFigure);
            app.CartesianPlaneqlimLabel.Text = 'Cartesian Plane (qlim)';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.RESUMEButton = uibutton(app.UIFigure, 'push');
            app.RESUMEButton.ButtonPushedFcn = createCallbackFcn(app, @RESUMEButtonPushed, true);
            app.RESUMEButton.Text = 'RESUME';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end